classdef LPMTT < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        LPMCOMDropDown          matlab.ui.control.DropDown
        LPMCOMDropDownLabel     matlab.ui.control.Label
        ToolCOMDropDown         matlab.ui.control.DropDown
        ToolCOMDropDownLabel    matlab.ui.control.Label
        OVERALLLamp             matlab.ui.control.Lamp
        OVERALLLampLabel        matlab.ui.control.Label
        GAINLamp                matlab.ui.control.Lamp
        GAINLampLabel           matlab.ui.control.Label
        VOSLamp                 matlab.ui.control.Lamp
        VOSLampLabel            matlab.ui.control.Label
        SNRLamp                 matlab.ui.control.Lamp
        SNRLampLabel            matlab.ui.control.Label
        PRESSURETEMPLamp        matlab.ui.control.Lamp
        PRESSURETEMPLampLabel   matlab.ui.control.Label
        PULSEOUTPUTSLamp        matlab.ui.control.Lamp
        PULSEOUTPUTSLampLabel   matlab.ui.control.Label
        VOLTAGELINESLamp        matlab.ui.control.Lamp
        VOLTAGELINESLampLabel   matlab.ui.control.Label
        SUPPLYLamp              matlab.ui.control.Lamp
        SUPPLYLampLabel         matlab.ui.control.Label
        LPMPRODUCTIONTESTLabel  matlab.ui.control.Label
        STARTTESTButton         matlab.ui.control.StateButton
    end

    
    properties (Access = private)
        LPMCOMp % Description
        XMEGACOMp
        FILEp
        SNUMp
        LPM
        XMEGA% Description
        IEXT
        IBAT
        VCCMAIN
        COREVDD
        VDD18
        VIO30
        VPP
        VIO18
        BLEVDD
        D0ERR
        D1ERR
    end
    
    methods (Access = private)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            availablePorts=serialportlist;
            app.ToolCOMDropDown.Items=availablePorts;
            app.LPMCOMDropDown.Items=availablePorts;
        end

        % Callback function
        function LPMCOM(app, event)
            app.LPMCOMp = app.LPMCOMDropDown.Value;
        end

        % Callback function
        function XMEGACOM(app, event)
            app.XMEGACOMp = app.ToolCOMDropDown.Value;
        end

        % Callback function
        function LOGFILENAME(app, event)
            app.FILEp = app.LOGFILENAMEEditField.Value;
        end

        % Callback function
        function LPMS(app, event)
            app.SNUMp = app.LPMSNUMEditField.Value;
        end

        % Value changed function: STARTTESTButton
        function START(app, event)
            pathl = [0.09, 0.09, 0.09, 0.09];
            cycles = 5;

            VCCMAINMIN = 3.22;
            VCCMAINMAX = 3.38;

            COREVDDMIN = 1.25;
            COREVDDMAX = 1.15;

            VDD18MIN = 1.76;
            VDD18MAX = 1.88;

            VIO30MIN = 2.93;
            VIO30MAX = 3.07;

            VPPMIN = 2.43;
            VPPMAX = 2.57;

            VIO18MIN = 1.76;
            VIO18MAX = 1.88;

            BLEVDDMIN = 2.93;
            BLEVDDMAX = 3.07;

            IB_AVRMIN = 3;
            IB_AVRMAX = 6;

            IE_AVRMIN = 3;
            IE_AVRMAX = 6;

            app.OVERALLLamp.Color = [0.65,0.65,0.65];
            app.GAINLamp.Color = [0.65,0.65,0.65];
            app.VOSLamp.Color = [0.65,0.65,0.65];
            app.SNRLamp.Color = [0.65,0.65,0.65];
            app.PRESSURETEMPLamp.Color = [0.65,0.65,0.65];
            app.PULSEOUTPUTSLamp.Color = [0.65,0.65,0.65];
            app.VOLTAGELINESLamp.Color = [0.65,0.65,0.65];
            app.SUPPLYLamp.Color = [0.65,0.65,0.65];


            filecheck = exist("history.csv","file");

            heading = {'date','serial_number','P/F','VCC_main','CORE_VDD','+1.8VDD','+3.0VIO','VPP_2V5','+1.8VIO','BLE_VDD','I_ext','I_bat','SNR1','SNR2','SNR3','SNR4','GAIN1','GAIN2','GAIN3','GAIN4','VOS1','VOS2','VOS3','VOS4','pressure','temp','D0_error','D1_error'};
            if filecheck == 0
                 writecell(heading,'history.csv','WriteMode','append');                 %check if logfile exists, if not create one with header
            end

            
            XMEGA = xmega(app.XMEGACOMp,115200);

            flush(XMEGA);

            write(XMEGA,'22222',"char");
            read(XMEGA,5,"char");
            pause(5);
            LPM = SET_BUS(app.LPMCOMp,115200);                              %create com object
            write(XMEGA,'22222',"char");
            IE1 = read(XMEGA,5,"char");
            %pause(0.1);
            write(XMEGA,'22222',"char");
            IE2 = read(XMEGA,5,"char");
            %pause(0.1);
            write(XMEGA,'22222',"char");
            IE3 = read(XMEGA,5,"char");
            %pause(0.1);
            write(XMEGA,'22222',"char");
            IE4 = read(XMEGA,5,"char");
            %pause(0.1);
            write(XMEGA,'22222',"char");
            IE5 = read(XMEGA,5,"char");
            
            IEV = [str2double(IE1),str2double(IE2),str2double(IE3),str2double(IE4),str2double(IE5)];
            IESUM = 0;

            for i = 1:5
                IESUM = IESUM + IEV(i);
            end

            IE_AVR = IESUM/5;
            IESUM = 0;

 %------------------------------------------------------------------------------------------------------------------------------------

            write(XMEGA,'11111',"char");
            read(XMEGA,5,"char");
            pause(0.4);
            write(XMEGA,'11111',"char");
            IB1 = read(XMEGA,5,"char");
            %pause(0.1);
            write(XMEGA,'11111',"char");
            IB2 = read(XMEGA,5,"char");
            %pause(0.1);
            write(XMEGA,'11111',"char");
            IB3 = read(XMEGA,5,"char");
            %pause(0.1);
            write(XMEGA,'11111',"char");
            IB4 = read(XMEGA,5,"char");
            %pause(0.1);
            write(XMEGA,'11111',"char");
            IB5 = read(XMEGA,5,"char");
            
            IBV = [str2double(IB1),str2double(IB2),str2double(IB3),str2double(IB4),str2double(IB5)];
            IBSUM = 0;

            for i = 1:5
                IBSUM = IBSUM + IEV(i);
            end

            IB_AVR = IBSUM/5;
            IBSUM = 0;


            %write(app.XMEGA,'11111',"char");
            %app.IBAT = read(app.XMEGA,5,"char");
            %write(app.XMEGA,'22222',"char");
            %app.IEXT = read(app.XMEGA,5,"char");
            %pause(1)
            %write(app.XMEGA,'22222',"char");
            %app.IEXT = read(app.XMEGA,5,"char");
            write(XMEGA,'55555',"char");
            app.VCCMAIN = read(XMEGA,1,"int16");
            app.COREVDD = read(XMEGA,1,"int16");
            app.VDD18 = read(XMEGA,1,"int16");
            app.VIO30 = read(XMEGA,1,"int16");
            app.VPP = read(XMEGA,1,"int16");
            app.VIO18 = read(XMEGA,1,"int16");
            app.BLEVDD = read(XMEGA,1,"int16");

            VCCMAIN = app.VCCMAIN;
            COREVDD = app.COREVDD;
            VDD18 = app.VDD18;
            VIO30 = app.VIO30;
            VPP = app.VPP;
            VIO18 = app.VIO18;
            BLEVDD = app.BLEVDD;
            
            SET_LPM(LPM,pathl);                                                     %configure the LPM
            SNR = GET_SNR(LPM,cycles);                                              %calculates avr snr on the paths
            GAIN = GET_GAIN(LPM,cycles);                                            %calculates avr GAIN on all paths
            VOS = GET_VOS(LPM,cycles);                                              %calculates acr VOS on paths
            PT = GET_PT(LPM);                                                       %reads pressure and temp

            %-----------------------------------------------------------------------------------------------------
            %write function test parameters LPM
            %read D0 from test tool
            app.D0ERR = GET_HZD0(LPM,XMEGA);


            if ((SNR(1) > 45)&&(SNR(2) > 45)&&(SNR(3) > 45)&&(SNR(4) > 45))
                    app.SNRLamp.Color = 'g';
                    SNRcheck = 1;
            else
                    app.SNRLamp.Color = 'r';
                    SNRcheck = 0;
            end

            if (((VOS(1) > 335)&&(VOS(1) < 348))&&((VOS(2) > 335)&&(VOS(2) < 348))&&((VOS(3) > 335)&&(VOS(3) < 348))&&((VOS(4) > 335)&&(VOS(4) < 348)))
                    app.VOSLamp.Color = 'g';
                    VOScheck = 1;
            else
                    app.VOSLamp.Color = 'r';
                    VOScheck = 0;
            end

            if (((GAIN(1) > 3000)&&(GAIN(1) < 5000))&&((GAIN(2) > 3000)&&(GAIN(2) < 5000))&&((GAIN(3) > 3000)&&(GAIN(3) < 5000))&&((GAIN(4) > 3000)&&(GAIN(4) < 5000)))
                    app.GAINLamp.Color = 'g';
                    GAINcheck = 1;
            else
                    app.GAINLamp.Color = 'r';
                    GAINcheck = 0;
            end

            if(PT(1)>0.8&&PT(1)<1.2)&&(PT(2)>15&&PT(2)<30)
                app.PRESSURETEMPLamp.Color = 'g';
                PTcheck = 1;
            else
                app.PRESSURETEMPLamp.Color = 'r';
                PTcheck = 0;
            end

            if((VCCMAIN>VCCMAINMIN&&VCCMAIN<VCCMAINMAX)&&(COREVDD>COREVDDMIN&&COREVDD<COREVDDMAX)&&(VDD18>VDD18MIN&&VDD18<VDD18MAX)&&(VIO30>VIO30MIN&&VIO30<VIO30MAX)&&(VPP>VPPMIN&&VPP<VPPMAX)&&(VIO18>VIO18MIN&&VIO18<VIO18MAX)&&(BLEVDD>BLEVDDMIN&&BLEVDD<BLEVDDMAX))
                app.VOLTAGELINESLamp.Color = 'g';
                Vcheck = 1;
            else
                app.VOLTAGELINESLamp.Color = 'r';
                Vcheck = 0;
            end

            if(IB_AVR > IB_AVRMIN && IB_AVR < IB_AVRMAX && IE_AVR > IE_AVRMIN && IE_AVR < IE_AVRMAX)
                app.SUPPLYLamp.Color = 'g';
                Scheck = 1;
            else
                app.SUPPLYLamp.Color = 'r';
                Scheck = 0;
            end

            if(SNRcheck==1&&VOScheck==1&&GAINcheck==1&&PTcheck==1&&Vcheck==1&&Scheck)
                app.OVERALLLamp.Color = 'g';
                PF = 'PASS';
            else
                app.OVERALLLamp.Color = 'r';
                PF = 'FAIL';
            end


            date = datetime('now');
            logdata = {date, app.SNUMp,PF,VCCMAIN,COREVDD,VDD18,VIO30,VPP,VIO18,BLEVDD,IE_AVR,IB_AVR,SNR(1),SNR(2),SNR(3),SNR(4),GAIN(1),GAIN(2),GAIN(3),GAIN(4),VOS(1),VOS(2),VOS(3),VOS(4),PT(1),PT(2),app.D0ERR,app.D1ERR};
            writecell(logdata, 'history.csv', 'WriteMode','append');

            clearvars app.LPM
        end

        % Drop down opening function: ToolCOMDropDown
        function ToolCOMDropDownOpening(app, event)
            availablePorts=serialportlist;
            app.ToolCOMDropDown.Items=availablePorts;
        end

        % Drop down opening function: LPMCOMDropDown
        function LPMCOMDropDownOpening(app, event)
            availablePorts=serialportlist;
            app.LPMCOMDropDown.Items=availablePorts;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.STARTTESTButton = uibutton(app.UIFigure, 'state');
            app.STARTTESTButton.ValueChangedFcn = createCallbackFcn(app, @START, true);
            app.STARTTESTButton.Text = 'START TEST';
            app.LPMPRODUCTIONTESTLabel = uilabel(app.UIFigure);
            app.LPMPRODUCTIONTESTLabel.Text = 'LPM PRODUCTION TEST';
            app.SUPPLYLampLabel = uilabel(app.UIFigure);
            app.SUPPLYLampLabel.Text = 'SUPPLY';
            app.SUPPLYLamp = uilamp(app.UIFigure);
            app.VOLTAGELINESLampLabel = uilabel(app.UIFigure);
            app.VOLTAGELINESLampLabel.Text = 'VOLTAGE LINES';
            app.VOLTAGELINESLamp = uilamp(app.UIFigure);
            app.PULSEOUTPUTSLampLabel = uilabel(app.UIFigure);
            app.PULSEOUTPUTSLampLabel.Text = 'PULSE OUTPUTS';
            app.PULSEOUTPUTSLamp = uilamp(app.UIFigure);
            app.PRESSURETEMPLampLabel = uilabel(app.UIFigure);
            app.PRESSURETEMPLampLabel.Text = 'PRESSURE & TEMP';
            app.PRESSURETEMPLamp = uilamp(app.UIFigure);
            app.SNRLampLabel = uilabel(app.UIFigure);
            app.SNRLampLabel.Text = 'SNR';
            app.SNRLamp = uilamp(app.UIFigure);
            app.VOSLampLabel = uilabel(app.UIFigure);
            app.VOSLampLabel.Text = 'VOS';
            app.VOSLamp = uilamp(app.UIFigure);
            app.GAINLampLabel = uilabel(app.UIFigure);
            app.GAINLampLabel.Text = 'GAIN';
            app.GAINLamp = uilamp(app.UIFigure);
            app.OVERALLLampLabel = uilabel(app.UIFigure);
            app.OVERALLLampLabel.Text = 'OVERALL';
            app.OVERALLLamp = uilamp(app.UIFigure);
            app.ToolCOMDropDownLabel = uilabel(app.UIFigure);
            app.ToolCOMDropDownLabel.Text = 'Tool COM';
            app.ToolCOMDropDown = uidropdown(app.UIFigure);
            app.ToolCOMDropDown.DropDownOpeningFcn = createCallbackFcn(app, @ToolCOMDropDownOpening, true);
            app.LPMCOMDropDownLabel = uilabel(app.UIFigure);
            app.LPMCOMDropDownLabel.Text = 'LPM COM';
            app.LPMCOMDropDown = uidropdown(app.UIFigure);
            app.LPMCOMDropDown.DropDownOpeningFcn = createCallbackFcn(app, @LPMCOMDropDownOpening, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = LPMTT

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end