classdef SUPTT < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        Amplitude120Button             matlab.ui.control.StateButton
        Amplitude85Button              matlab.ui.control.StateButton
        Amplitude0Button               matlab.ui.control.StateButton
        Amplitude70Button              matlab.ui.control.StateButton
        Amplitude40Button              matlab.ui.control.StateButton
        TestsettingsLabel              matlab.ui.control.Label
        TestslectLabel                 matlab.ui.control.Label
        ConnectButton                  matlab.ui.control.StateButton
        SupplyTestLabel                matlab.ui.control.Label
        Image                          matlab.ui.control.Image
        StarttestsButton               matlab.ui.control.StateButton
        RepeattestsntimesSpinner       matlab.ui.control.Spinner
        RepeattestsntimesSpinnerLabel  matlab.ui.control.Label
        TimebetweentestssecSpinner     matlab.ui.control.Spinner
        TimebetweentestssecSpinnerLabel  matlab.ui.control.Label
        ToolCOMDropDown                matlab.ui.control.DropDown
        ToolCOMDropDownLabel           matlab.ui.control.Label
        CallibrateToolButton           matlab.ui.control.StateButton
    end

    
    properties (Access = private)
            xmega                                                           %Global variable
            N                                                               %Repetisions
            T                                                               %Time between tests

            A120                                                            %Bolean for tests
            A85
            A70
            A40
            A0
    end
    
    methods (Access = private)

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            availablePorts=serialportlist;
            app.ToolCOMDropDown.Items=availablePorts;
        end

        % Value changed function: ConnectButton
        function ConnectButtonValueChanged(app, event)
            app.ConnectButton.Enable = "off";                               %Dissable button after pressed
            app.ToolCOMDropDown.Enable = "off";
            COM = app.ToolCOMDropDown.Value;

            try
                app.xmega = serialport(COM, 115200, 'Timeout', 25);         %Create serial object
            catch
                app.ConnectButton.Enable = "on";                            %When fail reanable button
                app.ToolCOMDropDown.Enable = "on";                          %When fail reanable dropdown
                app.ConnectButton.Value = false;                            %Make button(/America) white again
                return;
            end

            app.CallibrateToolButton.Enable = "on";                         %Enable the Calibrate tool button
            
        end

        % Value changed function: CallibrateToolButton
        function CallibrateToolButtonValueChanged(app, event)
            value = app.CallibrateToolButton.Value;
            app.CallibrateToolButton.Enable = "off";                        %Dissable button

            try
                write(app.xmega, 1, "uint8");                               %Callibrate Powersupply
                read(app.xmega, 1, "uint8");                                %Wait for cal to complete
                write(app.xmega, 2, "uint8");                               %Set output 100%
                read(app.xmega, 1, "single");                               
            catch
                app.CallibrateToolButton.Value = false;                     %Reset button colour
                app.CallibrateToolButton.Enable = "on";                     %Enable button
                return;
            end

            app.Amplitude120Button.Enable = "on";                           %Enalbe followup buttons
            app.Amplitude85Button.Enable = "on";
            app.Amplitude0Button.Enable = "on";
            app.Amplitude70Button.Enable = "on";
            app.Amplitude40Button.Enable = "on";
            app.RepeattestsntimesSpinner.Enable = "on";
            app.TimebetweentestssecSpinner.Enable = "on";
            app.StarttestsButton.Enable = "on";
            
        end

        % Value changed function: StarttestsButton
        function StarttestsButtonValueChanged(app, event)
            value = app.StarttestsButton.Value;
            app.N = app.RepeattestsntimesSpinner.Value;
            app.T = app.TimebetweentestssecSpinner.Value;

            app.A120 = app.Amplitude120Button.Value;                             %Set
            app.A85 = app.Amplitude85Button.Value;
            app.A0 = app.Amplitude0Button.Value;
            app.A70 = app.Amplitude70Button.Value;
            app.A40 = app.Amplitude40Button.Value;

            app.Amplitude120Button.Enable = "off";                           %Enalbe followup buttons
            app.Amplitude85Button.Enable = "off";
            app.Amplitude0Button.Enable = "off";
            app.Amplitude70Button.Enable = "off";
            app.Amplitude40Button.Enable = "off";
            app.RepeattestsntimesSpinner.Enable = "off";
            app.TimebetweentestssecSpinner.Enable = "off";
            app.StarttestsButton.Enable = "off";

%-----------------------------------------------------------------------------------------------------------            
            if app.A40 == true
                for i=1:app.N
                    write(app.xmega,3,"uint8");                                 %Start test 40% for 10ms
                    read(app.xmega,1,"uint8");
                    write(app.xmega, 10, "uint8");                              %LB time ms
                    read(app.xmega, 1, "uint8");
                    write(app.xmega, 0, "uint8");                               %HB time ms
                    read(app.xmega, 1,"uint8");
                    write(app.xmega, 40, "uint8");                              %Level
                    read(app.xmega, 1, "uint8");

                    pause(app.T);
                end

                for i=1:app.N
                    write(app.xmega,3,"uint8");                                 %Start test 40% for 30ms 
                    read(app.xmega,1,"uint8");
                    write(app.xmega, 30, "uint8");                              %LB time ms
                    read(app.xmega, 1, "uint8");
                    write(app.xmega, 0, "uint8");                               %HB time ms
                    read(app.xmega, 1,"uint8");
                    write(app.xmega, 40, "uint8");                              %Level
                    read(app.xmega, 1, "uint8");

                    pause(app.T);
                end

                for i=1:app.N
                    write(app.xmega,3,"uint8");                                 %Start test 40% for 100ms 
                    read(app.xmega,1,"uint8");
                    write(app.xmega, 100, "uint8");                             %LB time ms
                    read(app.xmega, 1, "uint8");
                    write(app.xmega, 0, "uint8");                               %HB time ms
                    read(app.xmega, 1,"uint8");
                    write(app.xmega, 40, "uint8");                              %Level
                    read(app.xmega, 1, "uint8");

                    pause(app.T);
                end
            end

%-----------------------------------------------------------------------------------------------------------
            if app.A70 == true
                for i=1:app.N
                    write(app.xmega,3,"uint8");                                 %Start test 70% for 10ms  
                    read(app.xmega,1,"uint8");
                    write(app.xmega, 10, "uint8");                              %LB time ms
                    read(app.xmega, 1, "uint8");
                    write(app.xmega, 0, "uint8");                               %HB time ms
                    read(app.xmega, 1,"uint8");
                    write(app.xmega, 70, "uint8");                              %Level
                    read(app.xmega, 1, "uint8");

                    pause(app.T);
                end

                for i=1:app.N
                    write(app.xmega,3,"uint8");                                 %Start test 70% for 30ms  
                    read(app.xmega,1,"uint8");
                    write(app.xmega, 30, "uint8");                              %LB time ms
                    read(app.xmega, 1, "uint8");
                    write(app.xmega, 0, "uint8");                               %HB time ms
                    read(app.xmega, 1,"uint8");
                    write(app.xmega, 70, "uint8");                              %Level
                    read(app.xmega, 1, "uint8");

                    pause(app.T);
                end

                for i=1:app.N
                    write(app.xmega,3,"uint8");                                 %Start test 70% for 100ms 
                    read(app.xmega,1,"uint8");
                    write(app.xmega, 100, "uint8");                              %LB time ms
                    read(app.xmega, 1, "uint8");
                    write(app.xmega, 0, "uint8");                               %HB time ms
                    read(app.xmega, 1,"uint8");
                    write(app.xmega, 70, "uint8");                              %Level
                    read(app.xmega, 1, "uint8");

                    pause(app.T);
                end
            end

 %-----------------------------------------------------------------------------------------------------------
 if app.A0 == true
     for i=1:app.N
            write(app.xmega,3,"uint8");                                 %Start test 0% for 1ms 
            read(app.xmega,1,"uint8");
            write(app.xmega, 1, "uint8");                               %LB time ms
            read(app.xmega, 1, "uint8");
            write(app.xmega, 0, "uint8");                               %HB time ms
            read(app.xmega, 1,"uint8");
            write(app.xmega, 0, "uint8");                               %Level
            read(app.xmega, 1, "uint8");

            pause(app.T);
     end

     for i=1:app.N
         write(app.xmega,3,"uint8");                                 %Start test 0% for 3ms  
         read(app.xmega,1,"uint8");
         write(app.xmega, 3, "uint8");                               %LB time ms
         read(app.xmega, 1, "uint8");
         write(app.xmega, 0, "uint8");                               %HB time ms
         read(app.xmega, 1,"uint8");
         write(app.xmega, 0, "uint8");                               %Level
         read(app.xmega, 1, "uint8");

         pause(app.T);
     end

     for i=1:app.N
        write(app.xmega,3,"uint8");                                 %Start test 0% for 10ms  
        read(app.xmega,1,"uint8");
        write(app.xmega, 10, "uint8");                              %LB time ms
        read(app.xmega, 1, "uint8");
        write(app.xmega, 0, "uint8");                               %HB time ms
        read(app.xmega, 1,"uint8");
        write(app.xmega, 0, "uint8");                               %Level
        read(app.xmega, 1, "uint8");

        pause(app.T);
     end
 end

 %-----------------------------------------------------------------------------------------------------
 if app.A85 == true
     for i=1:app.N
        write(app.xmega,3,"uint8");                                 %Start test 85% for 100ms  
        read(app.xmega,1,"uint8");
        write(app.xmega, 100, "uint8");                             %LB time ms
        read(app.xmega, 1, "uint8");
        write(app.xmega, 0, "uint8");                               %HB time ms
        read(app.xmega, 1,"uint8");
        write(app.xmega, 85, "uint8");                              %Level
        read(app.xmega, 1, "uint8");

        pause(app.T);
     end

     for i=1:app.N
        write(app.xmega,3,"uint8");                                 %Start test 85% for 300ms 
        read(app.xmega,1,"uint8");
        write(app.xmega, 44, "uint8");                              %LB time ms
        read(app.xmega, 1, "uint8");
        write(app.xmega, 1, "uint8");                               %HB time ms
        read(app.xmega, 1,"uint8");
        write(app.xmega, 85, "uint8");                              %Level
        read(app.xmega, 1, "uint8");

        pause(app.T);
     end

     for i=1:app.N
        write(app.xmega,3,"uint8");                                 %Start test 85% for 1000ms 
        read(app.xmega,1,"uint8");
        write(app.xmega, 232, "uint8");                             %LB time ms
        read(app.xmega, 1, "uint8");
        write(app.xmega, 3, "uint8");                               %HB time ms
        read(app.xmega, 1,"uint8");
        write(app.xmega, 85, "uint8");                              %Level
        read(app.xmega, 1, "uint8");

        pause(app.T);
     end

     for i = 1:app.N
        write(app.xmega,3,"uint8");                                 %Start test 85% for 3000ms  
        read(app.xmega,1,"uint8");
        write(app.xmega, 184, "uint8");                             %LB time ms
        read(app.xmega, 1, "uint8");
        write(app.xmega, 11, "uint8");                              %HB time ms
        read(app.xmega, 1,"uint8");
        write(app.xmega, 85, "uint8");                              %Level
        read(app.xmega, 1, "uint8");

        pause(app.T);
     end

     for i = 1:app.N
        write(app.xmega,3,"uint8");                                 %Start test 85% for 10000ms 
        read(app.xmega,1,"uint8");
        write(app.xmega, 16, "uint8");                              %LB time ms
        read(app.xmega, 1, "uint8");
        write(app.xmega, 39, "uint8");                              %HB time ms
        read(app.xmega, 1,"uint8");
        write(app.xmega, 85, "uint8");                              %Level
        read(app.xmega, 1, "uint8");

        pause(app.T);
     end
 end

 %-----------------------------------------------------------------------------------------------------
 if app.A120 == true
     for i=1:app.N
        write(app.xmega,3,"uint8");                                 %Start test 120% for 100ms
        read(app.xmega,1,"uint8");
        write(app.xmega, 100, "uint8");                             %LB time ms
        read(app.xmega, 1, "uint8");
        write(app.xmega, 0, "uint8");                               %HB time ms
        read(app.xmega, 1,"uint8");
        write(app.xmega, 120, "uint8");                             %Level
        read(app.xmega, 1, "uint8");

        pause(app.T);
     end

     for i=1:app.N
        write(app.xmega,3,"uint8");                                 %Start test 120% for 300ms    
        read(app.xmega,1,"uint8");
        write(app.xmega, 44, "uint8");                              %LB time ms
        read(app.xmega, 1, "uint8");
        write(app.xmega, 1, "uint8");                               %HB time ms
        read(app.xmega, 1,"uint8");
        write(app.xmega, 120, "uint8");                             %Level
        read(app.xmega, 1, "uint8");

        pause(app.T);
     end

     for i=1:app.N
        write(app.xmega,3,"uint8");                                 %Start test 120% for 1000ms  
        read(app.xmega,1,"uint8");
        write(app.xmega, 232, "uint8");                             %LB time ms
        read(app.xmega, 1, "uint8");
        write(app.xmega, 3, "uint8");                               %HB time ms
        read(app.xmega, 1,"uint8");
        write(app.xmega, 120, "uint8");                             %Level
        read(app.xmega, 1, "uint8");

        pause(app.T);
     end

     for i = 1:app.N
        write(app.xmega,3,"uint8");                                 %Start test 120% for 3000ms 
        read(app.xmega,1,"uint8");
        write(app.xmega, 184, "uint8");                             %LB time ms
        read(app.xmega, 1, "uint8");
        write(app.xmega, 11, "uint8");                              %HB time ms
        read(app.xmega, 1,"uint8");
        write(app.xmega, 120, "uint8");                             %Level
        read(app.xmega, 1, "uint8");

        pause(app.T);
     end

     for i = 1:app.N
        write(app.xmega,3,"uint8");                                 %Start test 120% for 10000ms   
        read(app.xmega,1,"uint8");
        write(app.xmega, 16, "uint8");                              %LB time ms
        read(app.xmega, 1, "uint8");
        write(app.xmega, 39, "uint8");                              %HB time ms
        read(app.xmega, 1,"uint8");
        write(app.xmega, 120, "uint8");                             %Level
        read(app.xmega, 1, "uint8");

        pause(app.T);
     end
 end

 write(app.xmega, 2, "uint8");                                      %Set output 100%
 read(app.xmega, 1, "single"); 
 
 app.StarttestsButton.Value = false;                                %Reset start button

 app.Amplitude120Button.Enable = "on";                              %Enalbe followup buttons
 app.Amplitude85Button.Enable = "on";
 app.Amplitude0Button.Enable = "on";
 app.Amplitude70Button.Enable = "on";
 app.Amplitude40Button.Enable = "on";
 app.RepeattestsntimesSpinner.Enable = "on";
 app.TimebetweentestssecSpinner.Enable = "on";
 app.StarttestsButton.Enable = "on";
        end

        % Drop down opening function: ToolCOMDropDown
        function ToolCOMDropDownOpening(app, event)
            availablePorts=serialportlist;
            app.ToolCOMDropDown.Items=availablePorts;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.CallibrateToolButton = uibutton(app.UIFigure, 'state');
            app.CallibrateToolButton.ValueChangedFcn = createCallbackFcn(app, @CallibrateToolButtonValueChanged, true);
            app.CallibrateToolButton.Enable = 'off';
            app.CallibrateToolButton.Text = 'Callibrate Tool';
            app.ToolCOMDropDownLabel = uilabel(app.UIFigure);
            app.ToolCOMDropDownLabel.Text = 'Tool COM';
            app.ToolCOMDropDown = uidropdown(app.UIFigure);
            app.ToolCOMDropDown.DropDownOpeningFcn = createCallbackFcn(app, @ToolCOMDropDownOpening, true);
            app.TimebetweentestssecSpinnerLabel = uilabel(app.UIFigure);
            app.TimebetweentestssecSpinnerLabel.Enable = 'off';
            app.TimebetweentestssecSpinnerLabel.Text = 'Time between tests (sec)';
            app.TimebetweentestssecSpinner = uispinner(app.UIFigure);
            app.TimebetweentestssecSpinner.Enable = 'off';
            app.RepeattestsntimesSpinnerLabel = uilabel(app.UIFigure);
            app.RepeattestsntimesSpinnerLabel.Enable = 'off';
            app.RepeattestsntimesSpinnerLabel.Text = 'Repeat tests n times';
            app.RepeattestsntimesSpinner = uispinner(app.UIFigure);
            app.RepeattestsntimesSpinner.Enable = 'off';
            app.StarttestsButton = uibutton(app.UIFigure, 'state');
            app.StarttestsButton.ValueChangedFcn = createCallbackFcn(app, @StarttestsButtonValueChanged, true);
            app.StarttestsButton.Enable = 'off';
            app.StarttestsButton.Text = 'Start tests';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'TRANSUS.png';
            app.SupplyTestLabel = uilabel(app.UIFigure);
            app.SupplyTestLabel.Text = 'Supply Test ';
            app.ConnectButton = uibutton(app.UIFigure, 'state');
            app.ConnectButton.ValueChangedFcn = createCallbackFcn(app, @ConnectButtonValueChanged, true);
            app.ConnectButton.Text = 'Connect';
            app.TestslectLabel = uilabel(app.UIFigure);
            app.TestslectLabel.Text = 'Test slect';
            app.TestsettingsLabel = uilabel(app.UIFigure);
            app.TestsettingsLabel.Text = 'Test settings';
            app.Amplitude40Button = uibutton(app.UIFigure, 'state');
            app.Amplitude40Button.Enable = 'off';
            app.Amplitude40Button.Text = 'Amplitude 40%';
            app.Amplitude70Button = uibutton(app.UIFigure, 'state');
            app.Amplitude70Button.Enable = 'off';
            app.Amplitude70Button.Text = 'Amplitude 70%';
            app.Amplitude0Button = uibutton(app.UIFigure, 'state');
            app.Amplitude0Button.Enable = 'off';
            app.Amplitude0Button.Text = 'Amplitude 0%';
            app.Amplitude85Button = uibutton(app.UIFigure, 'state');
            app.Amplitude85Button.Enable = 'off';
            app.Amplitude85Button.Text = 'Amplitude 85%';
            app.Amplitude120Button = uibutton(app.UIFigure, 'state');
            app.Amplitude120Button.Enable = 'off';
            app.Amplitude120Button.Text = 'Amplitude 120%';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SUPTT

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end