classdef TI_LPM < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        BnmrEditField       matlab.ui.control.EditField
        BnmrEditFieldLabel  matlab.ui.control.Label
        SnmrEditField       matlab.ui.control.EditField
        SnmrEditFieldLabel  matlab.ui.control.Label
        StartButton         matlab.ui.control.Button
        DUTDropDown         matlab.ui.control.DropDown
        DUTDropDownLabel    matlab.ui.control.Label
        ToolDropDown        matlab.ui.control.DropDown
        ToolDropDownLabel   matlab.ui.control.Label
        PFLamp              matlab.ui.control.Lamp
        PFLampLabel         matlab.ui.control.Label
        Image               matlab.ui.control.Image
        VOSLamp             matlab.ui.control.Lamp
        VOSLampLabel        matlab.ui.control.Label
        SNRLamp             matlab.ui.control.Lamp
        SNRLampLabel        matlab.ui.control.Label
        TempGauge           matlab.ui.control.LinearGauge
        TempGaugeLabel      matlab.ui.control.Label
        PressGauge_2        matlab.ui.control.LinearGauge
        PressGauge_2Label   matlab.ui.control.Label
        Vio30Gauge_2        matlab.ui.control.SemicircularGauge
        Vio30Gauge_2Label   matlab.ui.control.Label
        BLEVddGauge         matlab.ui.control.SemicircularGauge
        BLEVddGaugeLabel    matlab.ui.control.Label
        Vio18Gauge_2        matlab.ui.control.SemicircularGauge
        Vio18Gauge_2Label   matlab.ui.control.Label
        Vpp25Gauge_2        matlab.ui.control.SemicircularGauge
        Vpp25Gauge_2Label   matlab.ui.control.Label
        VccMainGauge        matlab.ui.control.SemicircularGauge
        VccMainGaugeLabel   matlab.ui.control.Label
        Vdd18Gauge          matlab.ui.control.SemicircularGauge
        Vdd18GaugeLabel     matlab.ui.control.Label
        CoreVddGauge        matlab.ui.control.SemicircularGauge
        CoreVddGaugeLabel   matlab.ui.control.Label
        ConnectButton       matlab.ui.control.Button
        ExtIinmAGauge       matlab.ui.control.LinearGauge
        ExtIinmAGaugeLabel  matlab.ui.control.Label
        GAINLamp            matlab.ui.control.Lamp
        GAINLampLabel       matlab.ui.control.Label
        BatIinuAGauge       matlab.ui.control.LinearGauge
        BatIinuAGaugeLabel  matlab.ui.control.Label
        FD0Gauge            matlab.ui.control.Gauge
        FD0GaugeLabel       matlab.ui.control.Label
        FD1Gauge            matlab.ui.control.Gauge
        FD1GaugeLabel       matlab.ui.control.Label
    end

    
    properties (Access = private)
        xmega % Description
        BatchNr
        SNmr
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            availablePorts=serialportlist;
            app.DUTDropDown.Items=availablePorts;
            app.ToolDropDown.Items=availablePorts;
        end

        % Callback function: DUTDropDown, ToolDropDown
        function ToolDropDownOpening(app, event)
            availablePorts=serialportlist;
            app.DUTDropDown.Items=availablePorts;
            app.ToolDropDown.Items=availablePorts;
        end

        % Drop down opening function: DUTDropDown
        function DUTDropDownOpening(app, event)
            availablePorts=serialportlist;
            app.DUTDropDown.Items=availablePorts;
            app.ToolDropDown.Items=availablePorts;
        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            try                                                                                     %Try connecting to Xmega
                XMEGACOM = app.ToolDropDown.Value;                                                  %Assign COM port to variable XMEGACOM
                app.xmega = open_TTL(XMEGACOM);                                                     %Set up Xmega serial object

                write(app.xmega, 1, "uint8");                                                       %enable external supply
           catch 
                figX = app.UIFigure;
                uialert(figX,'Cannot connect to test tool.', ...
                'Program Information','Icon','error','CloseFcn','uiresume(fig)')                    %Create error popup
                uiwait(figX)                                                                        %Continue when popup is gone
                return;                                                                             %Quit callback funtion
            end

            app.ConnectButton.Enable = 'off';
            app.StartButton.Enable = 'on';
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            app.StartButton.Enable = 'off';

            app.BatIinuAGauge.Value = 0;
            app.ExtIinmAGauge.Value = 0;

            app.VccMainGauge.Value = 0;
            app.CoreVddGauge.Value = 0;
            app.Vdd18Gauge.Value = 0;
            app.Vio30Gauge_2.Value = 0;
            app.Vpp25Gauge_2.Value = 0;
            app.Vio18Gauge_2.Value = 0;
            app.BLEVddGauge.Value = 0;

            app.PressGauge_2.Value = 0;
            app.TempGauge.Value = 0;

            app.FD0Gauge.Value = 0;
            app.FD1Gauge.Value = 0;

            try
                write(app.xmega, 1, "uint8");                                                       %Enable external supply
            catch
                figX = app.UIFigure;
                uialert(figX,'Failed to send command to tool.', ...
                'Program Information','Icon','error','CloseFcn','uiresume(fig)')                    %Create error popup
                uiwait(figX)                                                                        %Continue when popup is gone
                return;
            end
            
            if app.SnmrEditField.Value == ""
                figX = app.UIFigure;
                app.StartButton.Enable = 'on';
                uialert(figX,'Please enter serial number.', ...
                'Program Information','Icon','error','CloseFcn','uiresume(fig)')                    %Create error popup
                uiwait(figX)                                                                        %Continue when popup is gone
                return;                                                                             %Quit callback funtion
            end

            if app.BnmrEditField.Value == ""
                figX = app.UIFigure;
                app.StartButton.Enable = 'on';
                uialert(figX,'Please enter serial number.', ...
                'Program Information','Icon','error','CloseFcn','uiresume(fig)')                    %Create error popup
                uiwait(figX)                                                                        %Continue when popup is gone
                app.StartButton.Enable = 'on';
                return;                                                                             %Quit callback funtion
            end

           
            LPMCOM = app.DUTDropDown.Value;

            try
                pause(5);
                LPM = SET_BUS(LPMCOM,115200);                                                   %Setup LPM MODBUS object
            catch
                figL = app.UIFigure;
                uialert(figL,'Cannot connect to LPM.', ...
                'Program Information','Icon','error','CloseFcn','uiresume(fig)')                    %Create error popup
                uiwait(figL)                                                                        %Continue when popup is gone 
                return;                                                                             %Quit callback funtion
            end
            
            try

                app.BatchNr = app.BnmrEditField.Value;                                                                    %Set batch nr
                app.SNmr = app.SnmrEditField.Value;                                                                       %Set Serial nr

                Filename = sprintf('%s_%s.xlsx', app.BatchNr, datestr(now,'dd-mm-yyyy'));               %Set Filename

                filecheck = exist("testlog.xlsx", "file");                                              %Checks existance of template
                findnew = exist(sprintf("./logfiles/%s",Filename), "file");                             %Checks existance of new logfile

                if findnew == 0
                    status = copyfile("testlog.xlsx", sprintf("./logfiles/%s",Filename),"f");           %Copy's file to logfile folder
                end

                write(app.xmega, 4, "uint8");                                                           %Measure external current
                Ecurrent = read(app.xmega, 1, "single");                                                %Read external current measurement

               app.ExtIinmAGauge.Value = Ecurrent;

                write(app.xmega, 0, "uint8");                                                           %Supply battery
                pause(1);                                                                               %wait for supply to stabalize
                write(app.xmega, 3, "uint8");                                                           %Measure battery current
                Bcurrent = read(app.xmega, 1, "single");                                                %Read battery current 

               app.BatIinuAGauge.Value = Bcurrent;

                write(app.xmega, 5, "uint8");                                                           %Measure LPM voltage lines
                v1 = read(app.xmega, 1, "single");
                v2 = read(app.xmega, 1, "single");
                v3 = read(app.xmega, 1, "single");
                v4 = read(app.xmega, 1, "single");
                v5 = read(app.xmega, 1, "single");
                v6 = read(app.xmega, 1, "single");
                v7 = read(app.xmega, 1, "single");

                app.VccMainGauge.Value = v1;
                app.CoreVddGauge.Value = v2;
                app.Vdd18Gauge.Value = v3;
                app.Vio30Gauge_2.Value = v4;
                app.Vpp25Gauge_2.Value = v5;
                app.Vio18Gauge_2.Value = v6;
                app.BLEVddGauge.Value = v7;

                [frequencyD0, frequencyD1] = GET_HZ(LPM, app.xmega);                               %Read frequency D0 and D1 plus plot

                app.FD0Gauge.Value = frequencyD0;
                app.FD1Gauge.Value = frequencyD1;

                SNR = GET_SNR(LPM, 5);                                                             %Reads SNR and calculates avrage for all 4 paths
                GAIN = GET_GAIN(LPM, 5);                                                           %Reads GAIN and calculates avrage for all 4 paths
                VOS = GET_VOS(LPM, 5);                                                             %Reads VOS and calculates avrage for all 4 paths
                PT = GET_PT(LPM);                                                                  %Reads temp and pressure


                app.PressGauge_2.Value = PT(1);
                app.TempGauge.Value = PT(2);

                write(app.xmega, 2, "uint8");                                                          %Turn LPM power off

                app.SnmrEditField.Value = '';
            
            catch
                figX = app.UIFigure;
                uialert(figX,'Runtime error occured.', ...
                'Program Information','Icon','error','CloseFcn','uiresume(fig)')                    %Create error popup
                uiwait(figX)                                                                        %Continue when popup is gone
                return;
            end

          app.StartButton.Enable = 'on';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FD1GaugeLabel = uilabel(app.UIFigure);
            app.FD1GaugeLabel.Text = 'F D1';
            app.FD1Gauge = uigauge(app.UIFigure, 'circular');
            app.FD1Gauge.Limits = [0 1250];
            app.FD0GaugeLabel = uilabel(app.UIFigure);
            app.FD0GaugeLabel.Text = 'F D0';
            app.FD0Gauge = uigauge(app.UIFigure, 'circular');
            app.FD0Gauge.Limits = [0 1250];
            app.BatIinuAGaugeLabel = uilabel(app.UIFigure);
            app.BatIinuAGaugeLabel.Text = 'Bat I in uA';
            app.BatIinuAGauge = uigauge(app.UIFigure, 'linear');
            app.BatIinuAGauge.Limits = [0 500];
            app.GAINLampLabel = uilabel(app.UIFigure);
            app.GAINLampLabel.Text = 'GAIN';
            app.GAINLamp = uilamp(app.UIFigure);
            app.ExtIinmAGaugeLabel = uilabel(app.UIFigure);
            app.ExtIinmAGaugeLabel.Text = 'Ext I in mA';
            app.ExtIinmAGauge = uigauge(app.UIFigure, 'linear');
            app.ExtIinmAGauge.Limits = [0 5];
            app.ConnectButton = uibutton(app.UIFigure, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            app.CoreVddGaugeLabel = uilabel(app.UIFigure);
            app.CoreVddGaugeLabel.Text = 'Core Vdd';
            app.CoreVddGauge = uigauge(app.UIFigure, 'semicircular');
            app.CoreVddGauge.Limits = [0 3];
            app.Vdd18GaugeLabel = uilabel(app.UIFigure);
            app.Vdd18GaugeLabel.Text = 'Vdd 1.8';
            app.Vdd18Gauge = uigauge(app.UIFigure, 'semicircular');
            app.Vdd18Gauge.Limits = [0 3];
            app.VccMainGaugeLabel = uilabel(app.UIFigure);
            app.VccMainGaugeLabel.Text = 'Vcc Main';
            app.VccMainGauge = uigauge(app.UIFigure, 'semicircular');
            app.VccMainGauge.Limits = [0 5];
            app.Vpp25Gauge_2Label = uilabel(app.UIFigure);
            app.Vpp25Gauge_2Label.Text = 'Vpp 2.5';
            app.Vpp25Gauge_2 = uigauge(app.UIFigure, 'semicircular');
            app.Vpp25Gauge_2.Limits = [0 3];
            app.Vio18Gauge_2Label = uilabel(app.UIFigure);
            app.Vio18Gauge_2Label.Text = 'Vio 1.8';
            app.Vio18Gauge_2 = uigauge(app.UIFigure, 'semicircular');
            app.Vio18Gauge_2.Limits = [0 3];
            app.BLEVddGaugeLabel = uilabel(app.UIFigure);
            app.BLEVddGaugeLabel.Text = 'BLE Vdd';
            app.BLEVddGauge = uigauge(app.UIFigure, 'semicircular');
            app.BLEVddGauge.Limits = [0 5];
            app.Vio30Gauge_2Label = uilabel(app.UIFigure);
            app.Vio30Gauge_2Label.Text = 'Vio 3.0';
            app.Vio30Gauge_2 = uigauge(app.UIFigure, 'semicircular');
            app.Vio30Gauge_2.Limits = [0 5];
            app.PressGauge_2Label = uilabel(app.UIFigure);
            app.PressGauge_2Label.Text = 'Press';
            app.PressGauge_2 = uigauge(app.UIFigure, 'linear');
            app.PressGauge_2.Limits = [-250 50];
            app.PressGauge_2.MajorTicks = [-250 -200 -150 -100 -50 0 50];
            app.PressGauge_2.MajorTickLabels = {'-250', '-200', '-150', '-100', '-50', '0', '50'};
            app.TempGaugeLabel = uilabel(app.UIFigure);
            app.TempGaugeLabel.Text = 'Temp';
            app.TempGauge = uigauge(app.UIFigure, 'linear');
            app.TempGauge.Limits = [-30 30];
            app.SNRLampLabel = uilabel(app.UIFigure);
            app.SNRLampLabel.Text = 'SNR';
            app.SNRLamp = uilamp(app.UIFigure);
            app.VOSLampLabel = uilabel(app.UIFigure);
            app.VOSLampLabel.Text = 'VOS';
            app.VOSLamp = uilamp(app.UIFigure);
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'TRANSUS.png';
            app.PFLampLabel = uilabel(app.UIFigure);
            app.PFLampLabel.Text = 'P/F';
            app.PFLamp = uilamp(app.UIFigure);
            app.ToolDropDownLabel = uilabel(app.UIFigure);
            app.ToolDropDownLabel.Text = 'Tool';
            app.ToolDropDown = uidropdown(app.UIFigure);
            app.ToolDropDown.DropDownOpeningFcn = createCallbackFcn(app, @ToolDropDownOpening, true);
            app.DUTDropDownLabel = uilabel(app.UIFigure);
            app.DUTDropDownLabel.Text = 'DUT';
            app.DUTDropDown = uidropdown(app.UIFigure);
            app.DUTDropDown.DropDownOpeningFcn = createCallbackFcn(app, @DUTDropDownOpening, true);
            app.DUTDropDown.ValueChangedFcn = createCallbackFcn(app, @ToolDropDownOpening, true);
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Enable = 'off';
            app.StartButton.Text = 'Start';
            app.SnmrEditFieldLabel = uilabel(app.UIFigure);
            app.SnmrEditFieldLabel.Text = 'Snmr';
            app.SnmrEditField = uieditfield(app.UIFigure, 'text');
            app.BnmrEditFieldLabel = uilabel(app.UIFigure);
            app.BnmrEditFieldLabel.Text = 'Bnmr';
            app.BnmrEditField = uieditfield(app.UIFigure, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TI_LPM

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end