classdef testApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        BatchNumberEditField        matlab.ui.control.EditField
        BatchNumberEditFieldLabel   matlab.ui.control.Label
        Image                       matlab.ui.control.Image
        PulseoutLamp                matlab.ui.control.Lamp
        PulseoutLampLabel           matlab.ui.control.Label
        SignalLamp                  matlab.ui.control.Lamp
        SignalLampLabel             matlab.ui.control.Label
        CurrentLamp                 matlab.ui.control.Lamp
        CurrentLampLabel            matlab.ui.control.Label
        VoltagesLamp                matlab.ui.control.Lamp
        VoltagesLampLabel           matlab.ui.control.Label
        OverallLamp                 matlab.ui.control.Lamp
        OverallLampLabel            matlab.ui.control.Label
        SerialNumberEditField       matlab.ui.control.EditField
        SerialNumberEditFieldLabel  matlab.ui.control.Label
        LPMCOMDropDown              matlab.ui.control.DropDown
        LPMCOMDropDownLabel         matlab.ui.control.Label
        ToolCOMDropDown             matlab.ui.control.DropDown
        ToolCOMDropDownLabel        matlab.ui.control.Label
        STARTTESTButton             matlab.ui.control.StateButton
        UIAxes2                     matlab.ui.control.UIAxes
        UIAxes                      matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        LPMCOMp % Description
        XMEGACOMp

        mySerialPortLPMCOMp
        mySerialPortXMEGACOMp


    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            availablePorts=serialportlist;
            app.ToolCOMDropDown.Items=availablePorts;
            app.LPMCOMDropDown.Items=availablePorts;
        end

        % Value changed function: STARTTESTButton
        function STARTTESTButtonValueChanged(app, event)

            pathl = [0.10, 0.10, 0.10, 0.10];                                                   %Path lengths LPM Test Tool
            cycles = 5;                                                                         %Amount of cycles for LPM avrage readings

            VCCMAINMIN = 3.22;                                                                  %Changable parameters to set pass or fail
            VCCMAINMAX = 3.38;

            COREVDDMIN = 1.25;
            COREVDDMAX = 1.15;

            VDD18MIN = 1.76;
            VDD18MAX = 1.88;

            VIO30MIN = 2.93;
            VIO30MAX = 3.07;

            VPPMIN = 2.43;
            VPPMAX = 2.57;

            VIO18MIN = 1.76;
            VIO18MAX = 1.88;

            BLEVDDMIN = 2.93;
            BLEVDDMAX = 3.07;

            IBMAX = 310;

            IEMAX = 750;

            VOSMIN = 308.7;
            VOSMAX = 377.3;

            SNRMIN = 45;

            GAINMIN = 30000;
            GAINMAX = 50000;

            FMIN = 990;
            FMAX = 1100;


            app.OverallLamp.Color = [0.94,0.94,0.94];                                           %Reset all indication lights
            app.VoltagesLamp.Color = [0.94,0.94,0.94];
            app.CurrentLamp.Color = [0.94,0.94,0.94];
            app.PulseoutLamp.Color = [0.94,0.94,0.94];
            app.SignalLamp.Color = [0.94,0.94,0.94];

            if app.SerialNumberEditField.Value == ""
                figX = app.UIFigure;
                uialert(figX,'Please enter serial number.', ...
                'Program Information','Icon','error','CloseFcn','uiresume(fig)')                %Create error popup
                uiwait(figX)                                                                    %Continue when popup is gone
                return;                                                                         %Quit callback funtion
            end

            if app.BatchNumberEditField.Value == ""
                figX = app.UIFigure;
                uialert(figX,'Please enter serial number.', ...
                'Program Information','Icon','error','CloseFcn','uiresume(fig)')                %Create error popup
                uiwait(figX)                                                                    %Continue when popup is gone
                return;                                                                         %Quit callback funtion
            end


            if ~isempty(instrfind)                                                              %close any open connections in case they exist
                instrreset;
            end
            
           try                                                                                  %Try connecting to Xmega
                XMEGACOM = app.ToolCOMDropDown.Value;                                           %Assign COM port to variable XMEGACOM
                xmega = open_TTL(XMEGACOM);                                                     %Set up Xmega serial object
           catch 
                figX = app.UIFigure;
                uialert(figX,'Cannot connect to test tool.', ...
                'Program Information','Icon','error','CloseFcn','uiresume(fig)')                %Create error popup
                uiwait(figX)                                                                    %Continue when popup is gone
                return;                                                                         %Quit callback funtion
           end

           try                                                                                  %Try connecting to LPM
                write(xmega,10,"uint8");                                                        %Enable external supply + current
                Ecurrent = VConv((read(xmega,1,"uint16")./50)./3.3);                            %Reads external current NEEDS CALLIBRATING
                pause(10);                                                                      %Wait 5 seconds for LPM to power on
                LPMCOM = app.LPMCOMDropDown.Value;                                              %Assign COM port to variable LPMCOM
                LPMD = SET_BUS(LPMCOM,115200);                                                  %Setup LPM MODBUS object
           catch
                figL = app.UIFigure;
                uialert(figL,'Cannot connect to LPM.', ...
                'Program Information','Icon','error','CloseFcn','uiresume(fig)')                %Create error popup
                uiwait(figL)                                                                    %Continue when popup is gone 
                return;                                                                         %Quit callback funtion
           end

           BatchNr = app.BatchNumberEditField.Value;                                            %Set batch nr
           Filename = sprintf('%s_%s.xlsx', BatchNr, datestr(now,'dd-mm-yyyy'));                %Set Filename

           filecheck = exist("testlog.xlsx", "file");                                           %Checks existance of logfile
           findnew = exist(sprintf("./logfiles/%s",Filename), "file");                          %checks existance of newfile

           if findnew == 0
               status = copyfile("testlog.xlsx", sprintf("./logfiles/%s",Filename),"f");        %Copy's file to logfile folder
           end


           SET_LPM(LPMD,pathl);                                                                 %Setup PT, change path perameters
           
           write(xmega,10,"uint8");                                                             %Enable external supply + current
           Ecurrent = IConv(read(xmega,1,"uint16"));                                            %Reads external current NEEDS CALLIBRATING

           %pause(0.1);

           %write(xmega,20,"uint8");                                                             %Enable Battery supply + current
           %Bcurrent = IConv(read(xmega,1,"uint16"));                                            %Reads battery current NEEDS CALLIBRATING

           write(xmega,30,"uint8");
           v1 = VConv(read(xmega,1,"uint16"));                                                  %NEEDS CALLIBRATING
           v2 = VConv(read(xmega,1,"uint16"));                                                  %NEEDS CALLIBRATING
           v3 = VConv(read(xmega,1,"uint16"));                                                  %NEEDS CALLIBRATING
           v4 = VConv(read(xmega,1,"uint16"));                                                  %NEEDS CALLIBRATING
           v5 = VConv(read(xmega,1,"uint16"));                                                  %NEEDS CALLIBRATING
           v6 = VConv(read(xmega,1,"uint16"));                                                  %NEEDS CALLIBRATING
           v7 = VConv(read(xmega,1,"uint16"));                                                  %NEEDS CALLIBRATING

           pause(1);
           
           [samplesD0, frequencyD0, samplesD1, frequencyD1] = GET_HZ(LPMD, xmega);              %Read frequency D0 and D1 plus plot

           disp(frequencyD0)
           disp(frequencyD1) 

           plot(app.UIAxes,samplesD0);
           plot(app.UIAxes2,samplesD1);
           
           SNR = GET_SNR(LPMD,cycles);                                                          %Reads SNR and calculates avrage for all 4 paths
           GAIN = GET_GAIN(LPMD,cycles);                                                        %Reads GAIN and calculates avrage for all 4 paths
           VOS = GET_VOS(LPMD,cycles);                                                          %Reads VOS and calculates avrage for all 4 paths
           PT = GET_PT(LPMD);                                                                   %Reads temp and pressure

           %write(xmega, 60, "uint8")                                                           %Turns Vbat and Vext off (to usb power...)
            
           disp(v1)
           disp(v2)
           disp(v3)
           disp(v4)
           disp(v5)
           disp(v6)
           disp(v7)


           if((v1>0.1)&&(v2>0.1)&&(v2>0.1)&&(v3>0.1)&&(v4>0.1)&&(v5>0.1)&&(v7>0.1))
               VC = 1;
               app.VoltagesLamp.Color = 'g';
           else
               VC = 0;
               app.VoltagesLamp.Color = 'r';
           end

           if(Ecurrent<=IEMAX)%&&(Bcurrent<=IBMAX)
               IC = 1;
               app.CurrentLamp.Color = 'g';
           else 
               IC = 0;
               app.CurrentLamp.Color = 'r';
           end

           if (SNR(1)>=SNRMIN)&&(SNR(2)>=SNRMIN)&&(SNR(3)>=SNRMIN)
               SNRC = 1;
           else
               SNRC = 0;
           end

           if(GAIN(1)>=GAINMIN&&GAIN(1)<=GAINMAX)&&(GAIN(2)>=GAINMIN&&GAIN(2)<=GAINMAX)&&(GAIN(3)>=GAINMIN&&GAIN(3)<=GAINMAX)
               GAINC = 1;
           else
               GAINC = 0;
           end

           if(VOS(1)>=VOSMIN&&VOS(1)<=VOSMAX)&&(VOS(2)>=VOSMIN&&VOS(2)<=VOSMAX)&&(VOS(3)>=VOSMIN&&VOS(3)<=VOSMAX)
               VOSC = 1;
           else
               VOSC = 0;
           end

           if(SNRC==1&&GAINC==1&&VOSC==1)
                app.SignalLamp.Color = 'g';
           else
                app.SignalLamp.Color = 'r';
           end

           if((frequencyD0>900&&frequencyD0<1100)&&(frequencyD1>900&&frequencyD1<1100))
                FC = 1;
                app.PulseoutLamp.Color = 'g';
           else
                FC = 0;
                app.PulseoutLamp.Color = 'r';
           end
               
           if(VC==1)&&(IC==1)&&(SNRC==1)&&(GAINC==1)&&(VOSC==1)&&(FC==1)                        %Check all systems pass/ fail
                PF = 'PASS';
                app.OverallLamp.Color = 'g';
           else
                PF = 'FAIL';
                app.OverallLamp.Color = 'r';

           end

           date = datetime('now');                                                               %Read date and time from PC
           logdata = {app.SerialNumberEditField.Value,date,PF,v1,v2,v3,v4,v5,v6,v7,999,Ecurrent,frequencyD0,frequencyD1,VOS(1),VOS(2),VOS(3),VOS(4),SNR(1),SNR(2),SNR(3),SNR(4),SNR(5),SNR(6),SNR(7),SNR(8),GAIN(1),GAIN(2),GAIN(3),GAIN(4),GAIN(5),GAIN(6),GAIN(7),GAIN(8),PT(2),PT(1)};
          
           if filecheck == 0
                disp("Error:    File not found");                                                %Error message
           else
                writecell(logdata, sprintf("./logfiles/%s",Filename), 'WriteMode', 'append');    %Write data to existing file
           end

           app.SerialNumberEditField.Value = '';                                                 %Reset serial number field


        end

        % Drop down opening function: ToolCOMDropDown
        function ToolCOMDropDownOpening(app, event)
            availablePorts=serialportlist;
            app.ToolCOMDropDown.Items=availablePorts;
            app.LPMCOMDropDown.Items=availablePorts;
        end

        % Drop down opening function: LPMCOMDropDown
        function LPMCOMDropDownOpening(app, event)
            availablePorts=serialportlist;
            app.ToolCOMDropDown.Items=availablePorts;
            app.LPMCOMDropDown.Items=availablePorts;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'D0')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'D1')
            zlabel(app.UIAxes2, 'Z')
            app.STARTTESTButton = uibutton(app.UIFigure, 'state');
            app.STARTTESTButton.ValueChangedFcn = createCallbackFcn(app, @STARTTESTButtonValueChanged, true);
            app.STARTTESTButton.Text = 'START TEST';
            app.ToolCOMDropDownLabel = uilabel(app.UIFigure);
            app.ToolCOMDropDownLabel.Text = 'Tool COM';
            app.ToolCOMDropDown = uidropdown(app.UIFigure);
            app.ToolCOMDropDown.DropDownOpeningFcn = createCallbackFcn(app, @ToolCOMDropDownOpening, true);
            app.LPMCOMDropDownLabel = uilabel(app.UIFigure);
            app.LPMCOMDropDownLabel.Text = 'LPM COM';
            app.LPMCOMDropDown = uidropdown(app.UIFigure);
            app.LPMCOMDropDown.DropDownOpeningFcn = createCallbackFcn(app, @LPMCOMDropDownOpening, true);
            app.SerialNumberEditFieldLabel = uilabel(app.UIFigure);
            app.SerialNumberEditFieldLabel.Text = 'Serial Number';
            app.SerialNumberEditField = uieditfield(app.UIFigure, 'text');
            app.OverallLampLabel = uilabel(app.UIFigure);
            app.OverallLampLabel.Text = 'Overall';
            app.OverallLamp = uilamp(app.UIFigure);
            app.VoltagesLampLabel = uilabel(app.UIFigure);
            app.VoltagesLampLabel.Text = 'Voltages';
            app.VoltagesLamp = uilamp(app.UIFigure);
            app.CurrentLampLabel = uilabel(app.UIFigure);
            app.CurrentLampLabel.Text = 'Current';
            app.CurrentLamp = uilamp(app.UIFigure);
            app.SignalLampLabel = uilabel(app.UIFigure);
            app.SignalLampLabel.Text = 'Signal';
            app.SignalLamp = uilamp(app.UIFigure);
            app.PulseoutLampLabel = uilabel(app.UIFigure);
            app.PulseoutLampLabel.Text = 'Pulse out';
            app.PulseoutLamp = uilamp(app.UIFigure);
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'TRANSUS.png';
            app.BatchNumberEditFieldLabel = uilabel(app.UIFigure);
            app.BatchNumberEditFieldLabel.Text = 'Batch Number';
            app.BatchNumberEditField = uieditfield(app.UIFigure, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = testApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end